classdef mandelbrotFractals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComplexconstantDropDown         matlab.ui.control.DropDown
        ComplexconstantDropDownLabel    matlab.ui.control.Label
        SetButton_2                     matlab.ui.control.Button
        ImEditField_2                   matlab.ui.control.NumericEditField
        ImEditField_2Label              matlab.ui.control.Label
        ReEditField_2                   matlab.ui.control.NumericEditField
        ReEditField_2Label              matlab.ui.control.Label
        SetcenterforJuliasetLabel       matlab.ui.control.Label
        DrawJuliaButton                 matlab.ui.control.Button
        DrawMandelbrotButton            matlab.ui.control.Button
        ResetcenterButton               matlab.ui.control.Button
        ImEditField                     matlab.ui.control.NumericEditField
        ImEditFieldLabel                matlab.ui.control.Label
        ReEditField                     matlab.ui.control.NumericEditField
        ReEditFieldLabel                matlab.ui.control.Label
        ZoomSlider                      matlab.ui.control.Slider
        ZoomSliderLabel                 matlab.ui.control.Label
        ResetgraphsButton               matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        SetcenterforMandelbrotsetLabel  matlab.ui.control.Label
        AppforfractalsLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)

        end

        % Button pushed function: ResetgraphsButton
        function ResetgraphsButtonPushed(app, event)
             cla (app.UIAxes, 'reset');
             cla (app.UIAxes2, 'reset');
        end

        % Button pushed function: DrawMandelbrotButton
        function DrawMandelbrotButtonPushed(app, event)
            
            %cla (app.UIAxes, 'reset')

            f=@(z,tau)(z^2-tau);
            n=600;
            its=80;
            tol=1.0e6;
            colscale=128;
            colormap(app.UIAxes, gray(colscale));
            
            % generovani ruznych obrazu zmenou rozsahem x a y
            
            xmin=-1;
            xmax=2;
            ymin=-1;
            ymax=1;
            
            %xmin=-1;
            %xmax=2.25;
            %ymin=-1.5;
            %ymax=-ymin;
            
            deltax=xmax-xmin;
            deltay=ymax-ymin;
             
            X=zeros(n,n);
             
            for k=1:n+1
                for l=1:n+1
                    x=xmin+deltax*(k-1)/n;
                    y=ymin+deltay*(l-1)/n;
                    tau=x+1i*y;
                    z=f(0,tau);
                    m=0;
                    while norm(z)<tol && m<its 
                        z=f(z,tau);
                        m=m+1;
                    end
                    if norm(z)>tol || isinf(z) || isnan(z)
                        X(l,k)=round(colscale*m/its);
                    end
                end
            end
      
            
            image(app.UIAxes, X)
            axis(app.UIAxes, 'off')
            colormap(app.UIAxes, 'default');

% neoptimalizovany algoritmus vykreslovani            

%             MAX_NUM_OF_COMPUTATIONS = 200;
%             DRAW_AROUND_RE = -1.4;
%             DRAW_AROUND_IM = 0;
%             RE_MIN = -2;
%             RE_MAX = 1;
%             IM_MIN = -1;
%             IM_MAX = 1;
% 
%             zoom = app.ZoomSlider.Value;
%             step = 0.01;
%             realPartPlot = DRAW_AROUND_RE+(RE_MIN-DRAW_AROUND_RE/zoom):(step/zoom):DRAW_AROUND_RE+(RE_MAX-DRAW_AROUND_RE/zoom);
%             imaginaryPartPlot = DRAW_AROUND_IM+(IM_MIN/zoom):(step/zoom):DRAW_AROUND_IM+(IM_MAX/zoom);
% 
%             hold (app.UIAxes, 'on')
%             %set(app.UIAxes,'color', 'blue');
% 
%             for re = realPartPlot
%                 for im = imaginaryPartPlot
%                     c = re + im*1i;
%                     deviation = mandelbrotCheck(c, MAX_NUM_OF_COMPUTATIONS);
%                     if(deviation == MAX_NUM_OF_COMPUTATIONS)
%                         plot(app.UIAxes, re, im, 'black.')      
%                     elseif(deviation > 2*(MAX_NUM_OF_COMPUTATIONS/3) && deviation < MAX_NUM_OF_COMPUTATIONS)
%                         plot(app.UIAxes, re, im, 'red.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/2 && deviation < 2*(MAX_NUM_OF_COMPUTATIONS/3))
%                         plot(app.UIAxes, re, im, 'magenta.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/3 && deviation < MAX_NUM_OF_COMPUTATIONS/2)
%                         plot(app.UIAxes, re, im, 'yellow.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/8 && deviation < MAX_NUM_OF_COMPUTATIONS/3)
%                         plot(app.UIAxes, re, im, 'green.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/16 && deviation < MAX_NUM_OF_COMPUTATIONS/8)
%                         plot(app.UIAxes, re, im, 'cyan.')
%                     end
%                 end
%             end
        end

        % Button pushed function: DrawJuliaButton
        function DrawJuliaButtonPushed(app, event)
            expression = app.ComplexconstantDropDown.Value;
            expressionCut = strsplit(expression, "+");
            realPart = str2double(expressionCut(1));
            foo = string(expressionCut(2));
            expressionCut2 = strsplit(foo(1), "*");
            imPart1 = str2double(expressionCut2(1));
            imPart2 = str2double(expressionCut2(2));

            c = realPart + imPart1 * imPart2;

            colscale=128;
            colormap(app.UIAxes2, gray(colscale));

            col=30;
            m=400;
            cx=0;
            cy=0;
            l=1.5;

            x=linspace(cx-l,cx+l,m);
            y=linspace(cy-l,cy+l,m);
            [X,Y]=meshgrid(x,y);

            Z=X+1i*Y;

            for k=1:col
                Z=Z.^2+c;
                W=exp(-abs(Z));
            end
            
            % defaultni colormap je celkem humus
            colormap(app.UIAxes2, jet(256));
            pcolor(app.UIAxes2, W);
            shading (app.UIAxes2, 'flat');
            axis(app.UIAxes2, 'square','equal','off');

        end

        % Button pushed function: ResetcenterButton
        function ResetcenterButtonPushed(app, event)
            defaultValue = 0;
            app.ReEditField.Value = defaultValue;
            app.ImEditField.Value = defaultValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Julia fractals')
            xlabel(app.UIAxes2, 'Re')
            ylabel(app.UIAxes2, {'Im'; ''})
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Mandelbrot fractals'; ''})
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            zlabel(app.UIAxes, 'Z')
            app.AppforfractalsLabel = uilabel(app.UIFigure);
            app.AppforfractalsLabel.Text = 'App for fractals';
            app.SetcenterforMandelbrotsetLabel = uilabel(app.UIFigure);
            app.SetcenterforMandelbrotsetLabel.Text = 'Set center for Mandelbrot set:';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.Text = 'Set';
            app.ResetgraphsButton = uibutton(app.UIFigure, 'push');
            app.ResetgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphsButtonPushed, true);
            app.ResetgraphsButton.Text = 'Reset graphs';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [1 100];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 1;
            app.ReEditFieldLabel = uilabel(app.UIFigure);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImEditFieldLabel = uilabel(app.UIFigure);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetcenterButton = uibutton(app.UIFigure, 'push');
            app.ResetcenterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetcenterButtonPushed, true);
            app.ResetcenterButton.Text = 'Reset center';
            app.DrawMandelbrotButton = uibutton(app.UIFigure, 'push');
            app.DrawMandelbrotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMandelbrotButtonPushed, true);
            app.DrawMandelbrotButton.Text = 'Draw Mandelbrot';
            app.DrawJuliaButton = uibutton(app.UIFigure, 'push');
            app.DrawJuliaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawJuliaButtonPushed, true);
            app.DrawJuliaButton.Text = ' Draw Julia';
            app.SetcenterforJuliasetLabel = uilabel(app.UIFigure);
            app.SetcenterforJuliasetLabel.Text = 'Set center for Julia set:';
            app.ReEditField_2Label = uilabel(app.UIFigure);
            app.ReEditField_2Label.Text = 'Re';
            app.ReEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ImEditField_2Label = uilabel(app.UIFigure);
            app.ImEditField_2Label.Text = 'Im';
            app.ImEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SetButton_2 = uibutton(app.UIFigure, 'push');
            app.SetButton_2.Text = 'Set';
            app.ComplexconstantDropDownLabel = uilabel(app.UIFigure);
            app.ComplexconstantDropDownLabel.Text = 'Complex constant:';
            app.ComplexconstantDropDown = uidropdown(app.UIFigure);
            app.ComplexconstantDropDown.Items = {'-0.745429+0*1i', '-1.3+0.1*1i', '0.27334+-0.00742*1i', '0.11031031+-0.67037*1i', '-0.488679+-0.56790*1i', '-0.561321+0.641000*1i', '-0.745429+0.11308*1i'};
            app.ComplexconstantDropDown.Value = '-0.745429+0*1i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mandelbrotFractals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end